classdef tutorialApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GroupingGeneDropDown       matlab.ui.control.DropDown
        GroupingGeneDropDownLabel  matlab.ui.control.Label
        TimepointSlider            matlab.ui.control.Slider
        TimepointSliderLabel       matlab.ui.control.Label
        UIAxesSisControl           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        StateDataMeas % binary state data measurement structure
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, StateDataMeas)
            
            % timepoints slider
            numTimepoints=length(StateDataMeas);
            app.GroupingGeneDropDown.Items=fields(StateDataMeas(1).data.SisterNaive);
            app.TimepointSlider.Limits=[1,numTimepoints];
            app.TimepointSlider.MajorTickLabels=replace(cellstr(num2str([1:numTimepoints]'))',' ','');
            
            % times
            times=[StateDataMeas.time];
            
            app.StateDataMeas=StateDataMeas;

        end

        % Value changed function: TimepointSlider
        function TimepointSliderValueChanged(app, event)
            app.TimepointSlider.Value=round(app.TimepointSlider.Value);
            timepointValue = app.TimepointSlider.Value;
            groupingGene=app.GroupingGeneDropDown.Value;
            
            app.StateDataMeas
            SisterNaiveDataHigh=app.StateDataMeas(timepointValue).data.SisterNaive.(groupingGene).hi;
            SisterNaiveDataLow=app.StateDataMeas(timepointValue).data.SisterNaive.(groupingGene).lo;
            
            SisterControlDataHigh=app.StateDataMeas(timepointValue).data.SisterControl.(groupingGene).hi;
            SisterControlDataLow=app.StateDataMeas(timepointValue).data.SisterControl.(groupingGene).lo;

            
            % sister control plot
            numGenes=height(SisterControlDataHigh);
            %geneNames=
            hold(app.UIAxesSisControl,'on');
            plot(app.UIAxesSisControl,SisterControlDataHigh.phat,'go');
            plot(app.UIAxesSisControl,SisterControlDataLow.phat,'ko');
            app.UIAxesSisControl.YLim=[-1.1 1.1];
            
            %
        end

        % Callback function: GroupingGeneDropDown, UIAxesSisControl
        function DropDownValueChanged(app, event)
            value = app.GroupingGeneDropDown.Value;
            app.UIAxesSisControl.Title.String=value;
            plot(app.UIAxesSisControl,1:10,rand(10)')
            
        end

        % Drop down opening function: GroupingGeneDropDown
        function GroupingGeneDropDownOpening(app, event)
            %app.GroupingGeneDropDown.Items={'g1','g2'};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesSisControl = uiaxes(app.UIFigure);
            title(app.UIAxesSisControl, 'Title')
            xlabel(app.UIAxesSisControl, 'X')
            ylabel(app.UIAxesSisControl, 'Y')
            zlabel(app.UIAxesSisControl, 'Z')
            app.UIAxesSisControl.ButtonDownFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.TimepointSliderLabel = uilabel(app.UIFigure);
            app.TimepointSliderLabel.Text = 'Timepoint';
            app.TimepointSlider = uislider(app.UIFigure);
            app.TimepointSlider.ValueChangedFcn = createCallbackFcn(app, @TimepointSliderValueChanged, true);
            app.GroupingGeneDropDownLabel = uilabel(app.UIFigure);
            app.GroupingGeneDropDownLabel.Text = 'GroupingGene';
            app.GroupingGeneDropDown = uidropdown(app.UIFigure);
            app.GroupingGeneDropDown.DropDownOpeningFcn = createCallbackFcn(app, @GroupingGeneDropDownOpening, true);
            app.GroupingGeneDropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end